classdef sleepbreath_anaysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        ExportButton             matlab.ui.control.Button
        ChoosefileButton         matlab.ui.control.Button
        FlowPanel                matlab.ui.container.Panel
        MaxEditFieldLabel        matlab.ui.control.Label
        MaxEditField             matlab.ui.control.NumericEditField
        MinEditFieldLabel        matlab.ui.control.Label
        MinEditField             matlab.ui.control.NumericEditField
        AverageEditFieldLabel    matlab.ui.control.Label
        AverageEditField         matlab.ui.control.NumericEditField
        FileListListBoxLabel     matlab.ui.control.Label
        FileListListBox          matlab.ui.control.ListBox
        VolumnPanel              matlab.ui.container.Panel
        MaxEditField_2Label      matlab.ui.control.Label
        MaxEditField_2           matlab.ui.control.NumericEditField
        MinEditField_2Label      matlab.ui.control.Label
        MinEditField_2           matlab.ui.control.NumericEditField
        AverageEditField_2Label  matlab.ui.control.Label
        AverageEditField_2       matlab.ui.control.NumericEditField
        SmoothSliderLabel        matlab.ui.control.Label
        SmoothSlider             matlab.ui.control.Slider
        sEditField_3             matlab.ui.control.NumericEditField
        sEditField_4             matlab.ui.control.NumericEditField
        MinspaceSliderLabel      matlab.ui.control.Label
        MinspaceSlider           matlab.ui.control.Slider
        ManualPanel              matlab.ui.container.Panel
        MaxEditField_3Label      matlab.ui.control.Label
        MaxEditField_3           matlab.ui.control.NumericEditField
        MinEditField_3Label      matlab.ui.control.Label
        MinEditField_3           matlab.ui.control.NumericEditField
        SettingButton            matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        StarttimeLabel           matlab.ui.control.Label
        StarttimeSlider          matlab.ui.control.Slider
        StoptimeLabel            matlab.ui.control.Label
        StoptimeSlider           matlab.ui.control.Slider
        sEditFieldLabel          matlab.ui.control.Label
        sEditField               matlab.ui.control.NumericEditField
        sEditField_2Label        matlab.ui.control.Label
        sEditField_2             matlab.ui.control.NumericEditField
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        count = 1;
        path;
        startTime = 0; stopTime = 0;
        var; int_var; s_int_var;
        time;
        indexSel;
        pks; locs;
        lpks; llocs;
        x; y;
        tmv; mv;
    end
    
    methods (Access = private)
        
        function [x,y,locs,pks,tmv,mv,llocs,lpks] = min_ventilation(app, volume,times)
            [pks,locs] = findpeaks(volume,'MinPeakProminence',0.001,'MinPeakDistance',app.MinspaceSlider.Value);
            mv = zeros(size(locs,1),1);
            tmv = zeros(size(locs,1),1);
            llocs = zeros(size(locs,1),1);
            lpks = zeros(size(locs,1),1);
            limit = 20;
            check = 0;
            for i = 1:size(locs,1)
                tmp = locs(i);
                while check == 0
                    if volume(tmp)-volume(tmp-1) < 0
                        if times(locs(i)) - times(tmp) > limit
                            lpks(i) = volume(tmp);
                            llocs(i) = times(tmp);
                            tmv(i) = times(locs(i))-times(tmp);
                            mv(i) = (volume(locs(i))-volume(tmp))*60/tmv(i);
                            check = 1;
                        end
                    end
                    tmp = tmp-1;
                end
                check = 0;
            end
            x = []; y = [];
            for i = 1:size(locs,1)
                x = [x llocs(i) llocs(i) times(locs(i)) times(locs(i))];
                y = [y 0 mv(i) mv(i) 0];
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {915, 915};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {185, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function FilenameEditFieldValueChanged(app, event)
%             value = app.FilenameEditField.Value;
            
        end

        % Callback function
        function ComputeButtonPushed(app, event)
            
        end

        % Value changed function: StarttimeSlider
        function StarttimeSliderValueChanged(app, event)
            app.startTime = app.StarttimeSlider.Value;
            app.sEditField.Value = app.startTime;
            app.indexSel = find(sign(app.time-app.startTime)+1, 1) : find(sign(app.time-app.stopTime)+1, 1);
            
            value = app.FileListListBox.Value;
            plot(app.UIAxes,app.time, app.var);
            app.UIAxes.XLim = [app.startTime,app.stopTime];
            app.UIAxes.Title.String = ['Flow change : ',value];
            app.MinEditField.Value = min(app.var(app.indexSel));
            app.MaxEditField.Value = max(app.var(app.indexSel)); 
            app.AverageEditField.Value = sum(app.var(app.indexSel)/size(app.var(app.indexSel),1));  

            plot(app.UIAxes_2,app.time,app.int_var,app.time,app.s_int_var,'r',app.time(app.locs),app.pks,'bo',app.llocs,app.lpks,'ro');
            app.UIAxes_2.XLim = [app.startTime,app.stopTime];
            app.UIAxes_2.Title.String = ['Volume : ',value];
            app.MinEditField_2.Value = min(app.int_var(app.indexSel)); 
            app.MaxEditField_2.Value = max(app.int_var(app.indexSel)); 
            app.AverageEditField_2.Value = sum(app.int_var(app.indexSel)/size(app.int_var(app.indexSel),1));   
            
            area(app.UIAxes_3,app.x,app.y,'FaceColor','flat');
            app.UIAxes_3.XLim = [app.startTime,app.stopTime];
        end

        % Value changed function: sEditField
        function sEditFieldValueChanged(app, event)
            app.startTime = app.sEditField.Value;
            app.StarttimeSlider.Value = app.startTime;
            app.indexSel = find(sign(app.time-app.startTime)+1, 1) : find(sign(app.time-app.stopTime)+1, 1);
            
            value = app.FileListListBox.Value;
            plot(app.UIAxes,app.time, app.var);
            app.UIAxes.XLim = [app.startTime,app.stopTime];
            app.UIAxes.Title.String = ['Flow change : ',value];
            app.MinEditField.Value = min(app.var(app.indexSel));
            app.MaxEditField.Value = max(app.var(app.indexSel)); 
            app.AverageEditField.Value = sum(app.var(app.indexSel)/size(app.var(app.indexSel),1));  

            plot(app.UIAxes_2,app.time,app.int_var,app.time,app.s_int_var,'r',app.time(app.locs),app.pks,'bo',app.llocs,app.lpks,'ro');
            app.UIAxes_2.XLim = [app.startTime,app.stopTime];
            app.UIAxes_2.Title.String = ['Volume : ',value];
            app.MinEditField_2.Value = min(app.int_var(app.indexSel)); 
            app.MaxEditField_2.Value = max(app.int_var(app.indexSel)); 
            app.AverageEditField_2.Value = sum(app.int_var(app.indexSel)/size(app.int_var(app.indexSel),1)); 
            
            area(app.UIAxes_3,app.x,app.y,'FaceColor','flat');
            app.UIAxes_3.XLim = [app.startTime,app.stopTime];
        end

        % Value changed function: StoptimeSlider
        function StoptimeSliderValueChanged(app, event)
            app.stopTime = app.StoptimeSlider.Value;
            app.sEditField_2.Value = app.stopTime;
            app.indexSel = find(sign(app.time-app.startTime)+1, 1) : find(sign(app.time-app.stopTime)+1, 1);
            
            value = app.FileListListBox.Value;
            plot(app.UIAxes,app.time, app.var);
            app.UIAxes.XLim = [app.startTime,app.stopTime];
            app.UIAxes.Title.String = ['Flow change : ',value];
            app.MinEditField.Value = min(app.var(app.indexSel));
            app.MaxEditField.Value = max(app.var(app.indexSel)); 
            app.AverageEditField.Value = sum(app.var(app.indexSel)/size(app.var(app.indexSel),1));  

            plot(app.UIAxes_2,app.time,app.int_var,app.time,app.s_int_var,'r',app.time(app.locs),app.pks,'bo',app.llocs,app.lpks,'ro');
            app.UIAxes_2.XLim = [app.startTime,app.stopTime];
            app.UIAxes_2.Title.String = ['Volume : ',value];
            app.MinEditField_2.Value = min(app.int_var(app.indexSel)); 
            app.MaxEditField_2.Value = max(app.int_var(app.indexSel)); 
            app.AverageEditField_2.Value = sum(app.int_var(app.indexSel)/size(app.int_var(app.indexSel),1)); 
            
            area(app.UIAxes_3,app.x,app.y,'FaceColor','flat');
            app.UIAxes_3.XLim = [app.startTime,app.stopTime];
        end

        % Value changed function: sEditField_2
        function sEditField_2ValueChanged(app, event)
            app.stopTime = app.sEditField_2.Value;
            app.StoptimeSlider.Value = app.stopTime;
            app.indexSel = find(sign(app.time-app.startTime)+1, 1) : find(sign(app.time-app.stopTime)+1, 1);
            
            value = app.FileListListBox.Value;
            plot(app.UIAxes,app.time, app.var);
            app.UIAxes.XLim = [app.startTime,app.stopTime];
            app.UIAxes.Title.String = ['Flow change : ',value];
            app.MinEditField.Value = min(app.var(app.indexSel));
            app.MaxEditField.Value = max(app.var(app.indexSel)); 
            app.AverageEditField.Value = sum(app.var(app.indexSel)/size(app.var(app.indexSel),1));  

            plot(app.UIAxes_2,app.time,app.int_var,app.time,app.s_int_var,'r',app.time(app.locs),app.pks,'bo',app.llocs,app.lpks,'ro');
            app.UIAxes_2.XLim = [app.startTime,app.stopTime];
            app.UIAxes_2.Title.String = ['Volume : ',value];
            app.MinEditField_2.Value = min(app.int_var(app.indexSel)); 
            app.MaxEditField_2.Value = max(app.int_var(app.indexSel)); 
            app.AverageEditField_2.Value = sum(app.int_var(app.indexSel)/size(app.int_var(app.indexSel),1)); 
            
            area(app.UIAxes_3,app.x,app.y,'FaceColor','flat');
            app.UIAxes_3.XLim = [app.startTime,app.stopTime];
        end

        % Button pushed function: ChoosefileButton
        function ChoosefileButtonPushed(app, event)
            [filename,app.path.path{app.count}] = uigetfile({'*.txt'});
            if ~any(string(app.FileListListBox.Items) == filename)
                app.path.filename{app.count} = filename;
                app.FileListListBox.Items(app.count) = cellstr(filename);
                app.count = app.count+1;
            end
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            [filename,filepath] = uiputfile({'*.csv'},'儲存檔案',[app.FileListListBox.Value(1:end-4),'.csv']);
            app.indexSel = find(sign(app.time-app.startTime)+1, 1) : find(sign(app.time-app.stopTime)+1, 1);
            str = strcat(filepath,filename);
            data = [app.var(app.indexSel),app.d_var(app.indexSel)];
            peak = [app.locs',app.pks];
            header = array2table([data(1:size(peak,1),:),peak],'VariableNames',{'Flow','Volumn','Location','Value'});
            writetable(header,str);
%             dlmwrite(str, [data(1:size(peak,1),:),peak],'-append');
            dlmwrite(str, data(size(peak,1):end,:),'-append');
        end

        % Value changed function: FileListListBox
        function FileListListBoxValueChanged(app, event)
            value = app.FileListListBox.Value;
            filepath = [app.path.path{string(app.path.filename) == app.FileListListBox.Value},value];
            app.var = dlmread(filepath,'\n',1,0);
            app.var = app.var';
            samplerate = 1/25;
            len = size(app.var,1);
            app.time = 0:samplerate:(len-1)*samplerate;
            app.int_var = cumtrapz(app.time,app.var);
            app.s_int_var = smoothdata(app.int_var,'movmean',app.SmoothSlider.Value);
            
            % Flow
            plot(app.UIAxes,app.time, app.var);
            app.UIAxes.XLim = [min(app.time),max(app.time)];
            app.UIAxes.Title.String = ['Flow change : ',value];
            app.MinEditField.Value = min(app.var);
            app.MaxEditField.Value = max(app.var); 
            app.AverageEditField.Value = sum(app.var/size(app.var,1));  

            % Volume
            [app.x,app.y,app.locs,app.pks,app.tmv,app.mv, app.llocs, app.lpks] = app.min_ventilation(app.s_int_var,app.time);
            plot(app.UIAxes_2,app.time,app.int_var,app.time,app.s_int_var,'r',app.time(app.locs),app.pks,'bo',app.llocs,app.lpks,'ro');
            app.UIAxes_2.XLim = [min(app.time),max(app.time)];
            app.UIAxes_2.Title.String = ['Volume : ',value];
            app.MinEditField_2.Value = min(app.int_var); 
            app.MaxEditField_2.Value = max(app.int_var); 
            app.AverageEditField_2.Value = sum(app.int_var/size(app.int_var,1));
            
            % Minute ventilation
            area(app.UIAxes_3,app.x,app.y,'FaceColor','flat');
            app.UIAxes_3.XLim = [min(app.time),max(app.time)];
            app.StarttimeSlider.Limits = [0,max(app.time)];
            app.StoptimeSlider.Limits = [0,max(app.time)];
            app.StoptimeSlider.Value = max(app.time);
            app.sEditField_2.Value = max(app.time);
            app.startTime = min(app.time);
            app.stopTime = max(app.time);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ExportButton = uibutton(app.LeftPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ChoosefileButton = uibutton(app.LeftPanel, 'push');
            app.ChoosefileButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefileButtonPushed, true);
            app.ChoosefileButton.Text = 'Choose file';
            app.FlowPanel = uipanel(app.LeftPanel);
            app.FlowPanel.Title = 'Flow';
            app.MaxEditFieldLabel = uilabel(app.FlowPanel);
            app.MaxEditFieldLabel.Text = {'Max'; ''};
            app.MaxEditField = uieditfield(app.FlowPanel, 'numeric');
            app.MinEditFieldLabel = uilabel(app.FlowPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.FlowPanel, 'numeric');
            app.AverageEditFieldLabel = uilabel(app.FlowPanel);
            app.AverageEditFieldLabel.Text = 'Average';
            app.AverageEditField = uieditfield(app.FlowPanel, 'numeric');
            app.FileListListBoxLabel = uilabel(app.LeftPanel);
            app.FileListListBoxLabel.Text = 'File List';
            app.FileListListBox = uilistbox(app.LeftPanel);
            app.FileListListBox.Items = {};
            app.FileListListBox.ValueChangedFcn = createCallbackFcn(app, @FileListListBoxValueChanged, true);
            app.FileListListBox.Value = {};
            app.VolumnPanel = uipanel(app.LeftPanel);
            app.VolumnPanel.Title = 'Volumn';
            app.MaxEditField_2Label = uilabel(app.VolumnPanel);
            app.MaxEditField_2Label.Text = {'Max'; ''};
            app.MaxEditField_2 = uieditfield(app.VolumnPanel, 'numeric');
            app.MinEditField_2Label = uilabel(app.VolumnPanel);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.VolumnPanel, 'numeric');
            app.AverageEditField_2Label = uilabel(app.VolumnPanel);
            app.AverageEditField_2Label.Text = 'Average';
            app.AverageEditField_2 = uieditfield(app.VolumnPanel, 'numeric');
            app.SmoothSliderLabel = uilabel(app.LeftPanel);
            app.SmoothSliderLabel.Text = 'Smooth : ';
            app.SmoothSlider = uislider(app.LeftPanel);
            app.SmoothSlider.Limits = [0 200];
            app.SmoothSlider.Value = 150;
            app.sEditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.sEditField_3.Value = 150;
            app.sEditField_4 = uieditfield(app.LeftPanel, 'numeric');
            app.sEditField_4.Value = 40;
            app.MinspaceSliderLabel = uilabel(app.LeftPanel);
            app.MinspaceSliderLabel.Text = 'Min space  : ';
            app.MinspaceSlider = uislider(app.LeftPanel);
            app.MinspaceSlider.Value = 40;
            app.ManualPanel = uipanel(app.LeftPanel);
            app.ManualPanel.Title = 'Manual';
            app.MaxEditField_3Label = uilabel(app.ManualPanel);
            app.MaxEditField_3Label.Text = {'Max'; ''};
            app.MaxEditField_3 = uieditfield(app.ManualPanel, 'numeric');
            app.MinEditField_3Label = uilabel(app.ManualPanel);
            app.MinEditField_3Label.Text = 'Min';
            app.MinEditField_3 = uieditfield(app.ManualPanel, 'numeric');
            app.SettingButton = uibutton(app.ManualPanel, 'push');
            app.SettingButton.Text = 'Setting';
            app.RightPanel = uipanel(app.GridLayout);
            app.StarttimeLabel = uilabel(app.RightPanel);
            app.StarttimeLabel.Text = 'Start time : ';
            app.StarttimeSlider = uislider(app.RightPanel);
            app.StarttimeSlider.ValueChangedFcn = createCallbackFcn(app, @StarttimeSliderValueChanged, true);
            app.StoptimeLabel = uilabel(app.RightPanel);
            app.StoptimeLabel.Text = 'Stop time : ';
            app.StoptimeSlider = uislider(app.RightPanel);
            app.StoptimeSlider.ValueChangedFcn = createCallbackFcn(app, @StoptimeSliderValueChanged, true);
            app.sEditFieldLabel = uilabel(app.RightPanel);
            app.sEditFieldLabel.Text = 's';
            app.sEditField = uieditfield(app.RightPanel, 'numeric');
            app.sEditField.ValueChangedFcn = createCallbackFcn(app, @sEditFieldValueChanged, true);
            app.sEditField_2Label = uilabel(app.RightPanel);
            app.sEditField_2Label.Text = 's';
            app.sEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.sEditField_2.ValueChangedFcn = createCallbackFcn(app, @sEditField_2ValueChanged, true);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Flow change')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Flow')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Volume change')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Volumn')
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Minute ventilation')
            xlabel(app.UIAxes_3, 'Time (s)')
            ylabel(app.UIAxes_3, 'Volumn per min')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sleepbreath_anaysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end